classdef recomendSystem < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Interest6Spinner             matlab.ui.control.Spinner
        Interest6SpinnerLabel        matlab.ui.control.Label
        Interest7SpinnerLabel        matlab.ui.control.Label
        Interest7Spinner             matlab.ui.control.Spinner
        Interest5Spinner             matlab.ui.control.Spinner
        Interest4Spinner             matlab.ui.control.Spinner
        Interest4SpinnerLabel        matlab.ui.control.Label
        CheckBox_1                   matlab.ui.control.CheckBox
        Interest5SpinnerLabel        matlab.ui.control.Label
        Interest3Spinner             matlab.ui.control.Spinner
        Interest3SpinnerLabel        matlab.ui.control.Label
        Interest2Spinner             matlab.ui.control.Spinner
        Interest2SpinnerLabel        matlab.ui.control.Label
        Interest1Spinner             matlab.ui.control.Spinner
        Interest1SpinnerLabel        matlab.ui.control.Label
        SubmitButton                 matlab.ui.control.Button
        CheckBox_5                   matlab.ui.control.CheckBox
        CheckBox_4                   matlab.ui.control.CheckBox
        CheckBox_3                   matlab.ui.control.CheckBox
        CheckBox_2                   matlab.ui.control.CheckBox
        Area5Label                   matlab.ui.control.Label
        Area4Label                   matlab.ui.control.Label
        Area3Label                   matlab.ui.control.Label
        Area2Label                   matlab.ui.control.Label
        Area1Label                   matlab.ui.control.Label
        InterestsLabel               matlab.ui.control.Label
        AvailableResearchAreasLabel  matlab.ui.control.Label
        UITable                      matlab.ui.control.Table
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            
            available = [];
            interests = [];

            if(app.CheckBox_1.Value == true)
                available(end+1,1) = 1;
            end
              
            if(app.CheckBox_2.Value == true)
                available(end+1,1) = 2;
            end
            if(app.CheckBox_3.Value == true)
                available(end+1,1) = 3;
            end
            if(app.CheckBox_4.Value == true)
                available(end+1,1) = 4;
            end
            if(app.CheckBox_5.Value == true)
                available(end+1,1) = 5;
            end    
            
            for i=1:size(available)
                available(i)
            end            
            
             interests(end+1,1) = app.Interest1Spinner.Value;
             interests(end+1,1) = app.Interest2Spinner.Value;
             interests(end+1,1) = app.Interest3Spinner.Value;
             interests(end+1,1) = app.Interest4Spinner.Value;
             interests(end+1,1) = app.Interest5Spinner.Value;
             interests(end+1,1) = app.Interest6Spinner.Value;
             interests(end+1,1) = app.Interest7Spinner.Value;
            
            for i=1:size(interests)
                interests(i)
            end
             
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Research Area'; 'Recomendation'; 'Lecturer'};
            app.UITable.RowName = {};
            app.AvailableResearchAreasLabel = uilabel(app.UIFigure);
            app.AvailableResearchAreasLabel.Text = 'Available Research Areas';
            app.InterestsLabel = uilabel(app.UIFigure);
            app.InterestsLabel.Text = 'Interests';
            app.Area1Label = uilabel(app.UIFigure);
            app.Area1Label.Text = 'Area1';
            app.Area2Label = uilabel(app.UIFigure);
            app.Area2Label.Text = 'Area2';
            app.Area3Label = uilabel(app.UIFigure);
            app.Area3Label.Text = 'Area3';
            app.Area4Label = uilabel(app.UIFigure);
            app.Area4Label.Text = 'Area4';
            app.Area5Label = uilabel(app.UIFigure);
            app.Area5Label.Text = 'Area5';
            app.CheckBox_2 = uicheckbox(app.UIFigure);
            app.CheckBox_2.Text = '';
            app.CheckBox_3 = uicheckbox(app.UIFigure);
            app.CheckBox_3.Text = '';
            app.CheckBox_4 = uicheckbox(app.UIFigure);
            app.CheckBox_4.Text = '';
            app.CheckBox_5 = uicheckbox(app.UIFigure);
            app.CheckBox_5.Text = '';
            app.SubmitButton = uibutton(app.UIFigure, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.Interest1SpinnerLabel = uilabel(app.UIFigure);
            app.Interest1SpinnerLabel.Text = 'Interest1 ';
            app.Interest1Spinner = uispinner(app.UIFigure);
            app.Interest1Spinner.Limits = [0 5];
            app.Interest2SpinnerLabel = uilabel(app.UIFigure);
            app.Interest2SpinnerLabel.Text = 'Interest2 ';
            app.Interest2Spinner = uispinner(app.UIFigure);
            app.Interest2Spinner.Limits = [0 5];
            app.Interest3SpinnerLabel = uilabel(app.UIFigure);
            app.Interest3SpinnerLabel.Text = 'Interest3 ';
            app.Interest3Spinner = uispinner(app.UIFigure);
            app.Interest3Spinner.Limits = [0 5];
            app.Interest5SpinnerLabel = uilabel(app.UIFigure);
            app.Interest5SpinnerLabel.Text = 'Interest5';
            app.CheckBox_1 = uicheckbox(app.UIFigure);
            app.CheckBox_1.Text = '';
            app.Interest4SpinnerLabel = uilabel(app.UIFigure);
            app.Interest4SpinnerLabel.Text = 'Interest4 ';
            app.Interest4Spinner = uispinner(app.UIFigure);
            app.Interest4Spinner.Limits = [0 5];
            app.Interest5Spinner = uispinner(app.UIFigure);
            app.Interest5Spinner.Limits = [0 5];
            app.Interest7Spinner = uispinner(app.UIFigure);
            app.Interest7Spinner.Limits = [0 5];
            app.Interest7SpinnerLabel = uilabel(app.UIFigure);
            app.Interest7SpinnerLabel.Text = 'Interest7';
            app.Interest6SpinnerLabel = uilabel(app.UIFigure);
            app.Interest6SpinnerLabel.Text = 'Interest6 ';
            app.Interest6Spinner = uispinner(app.UIFigure);
            app.Interest6Spinner.Limits = [0 5];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = recomendSystem

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end